classdef MammographySimulatorApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ResetButton                    matlab.ui.control.Button
        PhantomthicknessDropDown       matlab.ui.control.DropDown
        PhantomthicknessDropDownLabel  matlab.ui.control.Label
        DistancebetweenPhantomandFilmObjecttoImageOIDDropDown  matlab.ui.control.DropDown
        DistancebetweenPhantomandFilmObjecttoImageOIDDropDownLabel  matlab.ui.control.Label
        Generate2DImagefor3DPhantomButton  matlab.ui.control.Button
        Generate1DProfileFrom2DphantomButton  matlab.ui.control.Button
        AngleindegreesDropDown         matlab.ui.control.DropDown
        AngleindegreesLabel            matlab.ui.control.Label
        DistancebetweenXRaySourceandPhantomSourcetoImageSIDDropDown  matlab.ui.control.DropDown
        DistancebetweenXRaySourceandPhantomLabel  matlab.ui.control.Label
        EnergyDropDown                 matlab.ui.control.DropDown
        EnergyDropDownLabel            matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        size2D = 256;  % Size for the 2D phantom
        size3D = 128;  % Size for the 3D phantom
        muValues = containers.Map({'50', '75', '100'}, [0.03, 0.06, 0.2]);
        image1D % To store the 1D profile
    end
    
    methods (Access = private)
        
            function phantom2d = generatePhantom2D(app)
                ellipseMatrix = [
                    1   .69   .92    0     0     0   

                 ];
                phantom2d = phantom(ellipseMatrix, app.size2D);
            end

            function image2D = simulateXRay(app, phantom, energy, SID, OID, angle, phantomThickness)
   
                 % Retrieve attenuation coefficient based on energy
                if isKey(app.muValues, num2str(energy))
                    mu = app.muValues(num2str(energy)); % Retrieve attenuation coefficient
                else
                    error('Energy level not defined in muValues map.');
                end
                
                % Scaling factor for angle 
                pathAdjustmentFactor = 1 / (1 + deg2rad(angle)); 
                
                [rows, cols] = size(phantom);  
                image2D = zeros(rows, cols);
                
                % Compute projection
                for row = 1:rows
                    for col = 1:cols
                        % Calculate the distance to the source 
                        distanceToSource = sqrt((SID + OID)^2 + (row - rows/2)^2 + (col - cols/2)^2);
                        
                        % Effective path length with adjustment for sharpness 
                        effectivePathLength = phantomThickness * pathAdjustmentFactor;
                        
                        % Attenuation through the phantom (single slice here, equivalent to sum in 3D)
                        totalAttenuation = exp(-mu * phantom(row, col) * effectivePathLength);
                        
                        % Calculate transmitted intensity (adjusted for distance and divergence)
                        transmittedIntensity = totalAttenuation / (distanceToSource^2);
                        
                        % Store transmitted intensity in image2D
                        image2D(row, col) = transmittedIntensity;
                    end
                end


                % store the 1D profile (sum along rows or columns)
                app.image1D = sum(image2D, 1);
          end

        function phantom3D = generate3DPhantom(app, phantomThickness)
              
             % Define the ellipsoids matrix
            ellipsoids = [
                0.1, 0.8, 0.8, 0.8, 0, 0, 0, 0, 0, 0;   % Lower density tissue
                0.5, 0.15, 0.15, 0.15, 0, 0, 0, 0, 0, 0  % Higher density lesion
            ];
          
            % Validate app.size3D
            if ~isscalar(app.size3D) || app.size3D <= 0
                error('app.size3D must be a positive scalar.');
            end
        
            % Generate the 3D phantom
            phantom3D = phantom3d(ellipsoids, app.size3D);
         
            % Scale the phantom in the Z-direction based on phantomThickness
            [dimX, dimY, dimZ] = size(phantom3D);
            phantom3D = imresize3(phantom3D, [dimX, dimY, round(dimZ * phantomThickness)]);
  
        end
        
       function image2D = simulateXRayFrom3D(app, phantom3D, energy, SID, OID, angle, rotationAxis, phantomThickness, resetSimulation)
          if resetSimulation
            image2D = zeros(size(phantom3D, 1), size(phantom3D, 2));
          end
    
        % Get the attenuation coefficient
        mu = app.muValues(num2str(energy));
        
        % Scaling factor for angle
        pathAdjustmentFactor = 1 / (1 + deg2rad(angle)); % Sharper at smaller angles
        
        [dimX, dimY, dimZ] = size(phantom3D);
        image2D = zeros(dimX, dimY);
        
        % Calculate projection
        for i = 1:dimX
            for j = 1:dimY
                % Distance from source to pixel
                distanceToSource = sqrt((SID + OID)^2 + (i - dimX/2)^2 + (j - dimY/2)^2);
                
                % Effective path length with adjustment for sharpness
                effectivePathLength = phantomThickness * pathAdjustmentFactor;
                
                % Attenuation through the phantom
                totalAttenuation = exp(-mu * sum(phantom3D(i, j, :), 3) * effectivePathLength);
                
                % Compute pixel intensity with inverse square law
                image2D(i, j) = totalAttenuation / (distanceToSource^2);
            end
        end
        
        % Normalize image
        image2D = (image2D - min(image2D(:))) / (max(image2D(:)) - min(image2D(:)));
       end
         
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Generate1DProfileFrom2DphantomButton
        function Generate1DProfileFrom2DphantomButtonPushed(app, event)
           % Retrieve inputs from the UI
            energy = str2double(app.EnergyDropDown.Value);
            angle = str2double(app.AngleindegreesDropDown.Value);
            SID = str2double(app.DistancebetweenXRaySourceandPhantomSourcetoImageSIDDropDown.Value);
            OID = str2double(app.DistancebetweenPhantomandFilmObjecttoImageOIDDropDown.Value);
            phantomThickness = str2double(app.PhantomthicknessDropDown.Value);
        
            % Generate phantom
            phantom = app.generatePhantom2D();
        
            % Simulate X-ray image
            %image2D = app.simulateXRay(phantom, energy, SID, OID, angle, phantomThickness);
            image2D = app.simulateXRay(phantom, energy, SID, OID, angle, phantomThickness);
            % Generate and plot 1D profile
            plot(app.UIAxes, app.image1D);
            title(app.UIAxes, '1D Profile');
            xlabel(app.UIAxes, 'Pixel Index');
            ylabel(app.UIAxes, 'Intensity');
            
   
        end

        % Button pushed function: Generate2DImagefor3DPhantomButton
        function Generate2DImagefor3DPhantomButtonPushed(app, event)
           % Get user inputs for energy, distance, and angle
           energy = str2double(app.EnergyDropDown.Value);
           SID = str2double(app.DistancebetweenXRaySourceandPhantomSourcetoImageSIDDropDown.Value);
           OID = str2double(app.DistancebetweenPhantomandFilmObjecttoImageOIDDropDown.Value);
           phantomThickness = str2double(app.PhantomthicknessDropDown.Value);
           angle = str2double(app.AngleindegreesDropDown.Value);

 
           % Choose the rotation axis (defaulting to X-axis)
           rotationAxis = [1, 0, 0];
        
           % Generate the 3D phantom
           phantom3D = app.generate3DPhantom(phantomThickness);
        
           % Set resetSimulation flag (for example, defaulting to false)
           resetSimulation = false;
        
           % Generate the 2D X-ray projection
           image2D = app.simulateXRayFrom3D(phantom3D, energy, SID, OID, angle, rotationAxis, phantomThickness, resetSimulation);

           % Display the generated 2D image on the axes
           imagesc(app.UIAxes, image2D);
           colormap(app.UIAxes, 'gray');
           title(app.UIAxes, '2D X-ray Image of 3D Phantom');
           colorbar(app.UIAxes);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            cla(app.UIAxes);
            app.PhantomthicknessDropDown.Value = '2';
            app.DistancebetweenXRaySourceandPhantomSourcetoImageSIDDropDown.Value = '30';
            app.DistancebetweenPhantomandFilmObjecttoImageOIDDropDown.Value = '0.1';
            app.EnergyDropDown.Value = '50';
            app.AngleindegreesDropDown.Value = '15';
            drawnow;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.EnergyDropDownLabel = uilabel(app.UIFigure);
            app.EnergyDropDownLabel.Text = 'Energy';
            app.EnergyDropDown = uidropdown(app.UIFigure);
            app.EnergyDropDown.Items = {'50', '75', '100'};
            app.EnergyDropDown.Value = '50';
            app.DistancebetweenXRaySourceandPhantomLabel = uilabel(app.UIFigure);
            app.DistancebetweenXRaySourceandPhantomLabel.Text = {'Distance between '; 'XRay Source and Phantom '; '(Source-to-Image (SID))'};
            app.DistancebetweenXRaySourceandPhantomSourcetoImageSIDDropDown = uidropdown(app.UIFigure);
            app.DistancebetweenXRaySourceandPhantomSourcetoImageSIDDropDown.Items = {'30', '65', '85'};
            app.DistancebetweenXRaySourceandPhantomSourcetoImageSIDDropDown.Value = '30';
            app.AngleindegreesLabel = uilabel(app.UIFigure);
            app.AngleindegreesLabel.Text = {'Angle'; ' in degrees'};
            app.AngleindegreesDropDown = uidropdown(app.UIFigure);
            app.AngleindegreesDropDown.Items = {'15', '45', '60', '85'};
            app.AngleindegreesDropDown.Value = '15';
            app.Generate1DProfileFrom2DphantomButton = uibutton(app.UIFigure, 'push');
            app.Generate1DProfileFrom2DphantomButton.ButtonPushedFcn = createCallbackFcn(app, @Generate1DProfileFrom2DphantomButtonPushed, true);
            app.Generate1DProfileFrom2DphantomButton.Text = {'Generate 1D Profile '; 'From 2D phantom'};
            app.Generate2DImagefor3DPhantomButton = uibutton(app.UIFigure, 'push');
            app.Generate2DImagefor3DPhantomButton.ButtonPushedFcn = createCallbackFcn(app, @Generate2DImagefor3DPhantomButtonPushed, true);
            app.Generate2DImagefor3DPhantomButton.Text = {'Generate 2D Image '; 'for 3D Phantom'};
            app.DistancebetweenPhantomandFilmObjecttoImageOIDDropDownLabel = uilabel(app.UIFigure);
            app.DistancebetweenPhantomandFilmObjecttoImageOIDDropDownLabel.Text = {'Distance between '; 'Phantom and Film'; '(Object-to-Image (OID))'};
            app.DistancebetweenPhantomandFilmObjecttoImageOIDDropDown = uidropdown(app.UIFigure);
            app.DistancebetweenPhantomandFilmObjecttoImageOIDDropDown.Items = {'0.1', '2', '5'};
            app.DistancebetweenPhantomandFilmObjecttoImageOIDDropDown.Value = '0.1';
            app.PhantomthicknessDropDownLabel = uilabel(app.UIFigure);
            app.PhantomthicknessDropDownLabel.Text = 'Phantom thickness ';
            app.PhantomthicknessDropDown = uidropdown(app.UIFigure);
            app.PhantomthicknessDropDown.Items = {'2', '4', '5', '10'};
            app.PhantomthicknessDropDown.Value = '2';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MammographySimulatorApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end